classdef Doan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GiithiunhmTab                   matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        GvThSHUNHQUCTHNHLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        NPHNGPHPTNHLabel                matlab.ui.control.Label
        TmNghimTab                      matlab.ui.container.Tab
        solanlapEditField               matlab.ui.control.NumericEditField
        SlnlpLabel                      matlab.ui.control.Label
        ketquanghiemEditField           matlab.ui.control.NumericEditField
        KtquLabel                       matlab.ui.control.Label
        nhapsaisochophepEditField       matlab.ui.control.NumericEditField
        SaischophpLabel                 matlab.ui.control.Label
        denbEditField                   matlab.ui.control.NumericEditField
        BLabel                          matlab.ui.control.Label
        tuaEditField                    matlab.ui.control.NumericEditField
        ALabel                          matlab.ui.control.Label
        nhapphuongtrinhEditField        matlab.ui.control.EditField
        NhpphngtrnhLabel                matlab.ui.control.Label
        BiTonTmNghimLabel               matlab.ui.control.Label
        batdauButton                    matlab.ui.control.Button
        chonphuongphaptimDropDown       matlab.ui.control.DropDown
        KhongphnlyABLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        NiSuyTab                        matlab.ui.container.Tab
        BiTonNiSuyLabel                 matlab.ui.control.Label
        NhapgiatrinsyEditField          matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel    matlab.ui.control.Label
        KetquansEditField               matlab.ui.control.EditField
        KtqunisuyEditFieldLabel         matlab.ui.control.Label
        DulieuyEditField                matlab.ui.control.EditField
        NhpliuxLabel_2                  matlab.ui.control.Label
        DulieuxEditField                matlab.ui.control.EditField
        NhpliuxLabel                    matlab.ui.control.Label
        KQdathucnsEditField             matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel     matlab.ui.control.Label
        LagrangeButton                  matlab.ui.control.Button
        NewtonButton                    matlab.ui.control.Button
        ChonPPnsLabel                   matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        HiQuyTab                        matlab.ui.container.Tab
        BiTonHiQuyLabel                 matlab.ui.control.Label
        R2EditField                     matlab.ui.control.EditField
        R2EditFieldLabel                matlab.ui.control.Label
        KtqudonEditField                matlab.ui.control.EditField
        KtqudonEditFieldLabel           matlab.ui.control.Label
        GitrcndonEditField              matlab.ui.control.EditField
        GitrcndonEditFieldLabel         matlab.ui.control.Label
        KetquansEditField_2             matlab.ui.control.EditField
        KtquhiquyLabel                  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        YEditField                      matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.EditField
        XEditFieldLabel                 matlab.ui.control.Label
        DonButton                       matlab.ui.control.Button
        ChyButton                       matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        oHmTab                          matlab.ui.container.Tab
        LuHyxa1trong2trckhisdngvthayigitrcntnhvbcnhyhLabel  matlab.ui.control.Label
        VDNhphms01x4015x305x2025x12Bcnhy025Gitrcntnh05NnsdngOh2Label  matlab.ui.control.Label
        VDNhpdliux01020304nhpdliuy009983019867029552038942Gitrcntnh02NnsdngSaiphntrungtmOh2SaiphntinliOhLabel  matlab.ui.control.Label
        BiTonoHmLabel                   matlab.ui.control.Label
        Giatricuay                      matlab.ui.control.EditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        Giatricuax                      matlab.ui.control.EditField
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        Nhapham                         matlab.ui.control.EditField
        NhpHmsEditFieldLabel            matlab.ui.control.Label
        GiatriH                         matlab.ui.control.NumericEditField
        NhpBchEditFieldLabel            matlab.ui.control.Label
        giatricantim                    matlab.ui.control.NumericEditField
        GitrcntnhEditFieldLabel         matlab.ui.control.Label
        HocLabel                        matlab.ui.control.Label
        Ketqua                          matlab.ui.control.NumericEditField
        KtQuButton                      matlab.ui.control.Button
        ChoSaiSo                        matlab.ui.container.ButtonGroup
        Oh2Button                       matlab.ui.control.ToggleButton
        OhButton                        matlab.ui.control.ToggleButton
        ChonPhuongPhap                  matlab.ui.container.ButtonGroup
        LuiButton                       matlab.ui.control.ToggleButton
        TrungTamButton                  matlab.ui.control.ToggleButton
        TienButton                      matlab.ui.control.ToggleButton
        TchPhnTab                       matlab.ui.container.Tab
        NoteKhinhpdliudangxvaythinhpNkhngcotacdungviNctinhtngslngimx1ivinhphamsthiNlabtbucvchngtrnhcnbitsontnhtchphntheotngphngphapcuthKhisdngpphinhthangthNphisiuln100Khisdungppsimpson38thiNphaichiahtcho3Khisdungppsimpson18thiNphaichnNumunhmscnhnhocmthphinhnmngLabel  matlab.ui.control.Label
        BiTonTchPhnLabel                matlab.ui.control.Label
        KtquaEditField                  matlab.ui.control.EditField
        KtquaEditFieldLabel             matlab.ui.control.Label
        NhpNEditField                   matlab.ui.control.EditField
        NhpNEditFieldLabel              matlab.ui.control.Label
        NhpcntinhtichphnEditField       matlab.ui.control.EditField
        NhpcntinhtichphnEditFieldLabel  matlab.ui.control.Label
        NhphamsEditField                matlab.ui.control.EditField
        NhphamsEditFieldLabel           matlab.ui.control.Label
        NhpdliuyEditField               matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2        matlab.ui.control.Label
        NhpdliuxEditField               matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PPsimpson38Button               matlab.ui.control.Button
        PPsimpson13Button               matlab.ui.control.Button
        PPhinhthangButton               matlab.ui.control.Button
        HocLabel_2                      matlab.ui.control.Label
    end


    methods (Access = private)
        function [x, y, mode] = getData(app)
            % Kiểm tra người dùng nhập theo dạng nào

            hasFunction = ~isempty(app.NhphamsEditField.Value);   % có nhập hàm hay không
            hasXY = ~isempty(app.NhpdliuxEditField.Value) && ~isempty(app.NhpdliuyEditField.Value);

            % TRƯỜNG HỢP 1: NHẬP HÀM SỐ
            if hasFunction
                mode = 'func';

                % Lấy hàm f(x)
                try
                    f = str2func(['@(x)', app.NhphamsEditField.Value]);
                catch
                    app.KtquaEditField.Value = 'Hàm nhập không hợp lệ!';
                    x = []; y = [];
                    return;
                end

                % Lấy a, b từ chuỗi nhập
                c = str2num(app.NhpcntinhtichphnEditField.Value);
                if numel(c) ~= 2
                    app.KtquaEditField.Value = 'Cận tích phân phải gồm 2 số!';
                    x = []; y = [];
                    return;
                end
                a = c(1);
                b = c(2);

                % Lấy N
                N = str2double(app.NhpNEditField.Value);
                if isnan(N) || N <= 0 || floor(N)~=N
                    app.KtquaEditField.Value = 'N phải là số nguyên dương!';
                    x = []; y = [];
                    return;
                end


                % Tạo dữ liệu
                x = linspace(a, b, N+1);
                y = f(x);
                return;
            end


            %  TRƯỜNG HỢP 2: NHẬP DỮ LIỆU X-Y
            if hasXY
                mode = 'data';

                try
                    x = str2num(app.NhpdliuxEditField.Value);
                    y = str2num(app.NhpdliuyEditField.Value);
                catch
                    app.KtquaEditField.Value = 'Dữ liệu x hoặc y không hợp lệ!';
                    x = []; y = [];
                    return;
                end

                if length(x) ~= length(y)
                    app.KtquaEditField.Value = 'x và y phải có cùng số phần tử!';
                    x = []; y = [];
                    return;
                end

                return;
            end


            %  KHÔNG NHẬP GÌ
            app.KtquaEditField.Value = 'Chưa nhập dữ liệu hoặc hàm!';
            x = []; y = [];
            mode = '';
        end
        % Simpson 1/3
        function I = simpson13(~, x, y)
            n = length(x) - 1;
            if mod(n, 2) ~= 0
                error('Simpson 1/3 yêu cầu n chẵn');
            end
            h = (x(end) - x(1)) / n;
            I = y(1) + y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2));
            I = I * h / 3;
        end

        % Simpson 3/8
        function I = simpson38(~, x, y)
            n = length(x) - 1;
            if mod(n,3) ~= 0
                error('Simpson 3/8 yêu cầu n chia hết cho 3');
            end
            h = (x(end) - x(1)) / n;
            I = y(1) + y(end);
            for i = 2:n
                if mod(i-1,3)==0
                    I = I + 2*y(i);
                else
                    I = I + 3*y(i);
                end
            end
            I = I * 3 * h / 8;
        end
        function [c,n] = chiadoi(app, f, a, b, eps)
            n = 0;
            % Basic checks
            try
                fa = f(a); fb = f(b);
            catch
                c = NaN; n = 0; return;
            end
            if ~isfinite(fa) || ~isfinite(fb)
                c = NaN; n = 0; return;
            end
            if fa * fb > 0
                c = NaN; n = 0; return;
            end

            maxIter = 10000;
            while true
                n = n + 1;
                c = (a + b) / 2;
                fc = f(c);

                % Nếu giá trị f(c) đủ nhỏ thì coi là nghiệm
                if ~isnan(fc) && abs(fc) < eps
                    return;
                end

                % Dừng theo khoảng [a,b]
                if (b - a) / 2 < eps
                    return;
                end

                if fa * fc < 0
                    b = c;
                    fb = fc;
                else
                    a = c;
                    fa = fc;
                end

                if n >= maxIter
                    c = NaN;
                    return;
                end
            end
        end

        function [x1,n] = lap(app, f, x0, eps)
            n = 0;
            maxIter = 10000;
            x_old = x0;
            lambda_list = [1, 0.8, 0.5, 0.2, 0.1, 0.05, 0.02, 0.01];

            for L = lambda_list
                x_old = x0;
                n_local = 0;
                while true
                    n_local = n_local + 1;
                    try
                        fx = f(x_old);
                    catch
                        break;
                    end

                    x_new = x_old - L * fx;

                    % kiểm tra hội tụ
                    if ~isfinite(x_new)
                        break;
                    end

                    if abs(x_new - x_old) < eps
                        x1 = x_new;
                        n = n_local;
                        return;
                    end

                    x_old = x_new;

                    if n_local >= maxIter
                        break;
                    end
                end
            end

            % Nếu không hội tụ
            x1 = NaN;
            n = 0;
        end


        function [x1,n] = tieptuyen(app, f, x0, eps)
            n = 0;
            maxIter = 1000;
            x_old = x0;

            for k = 1:maxIter
                n = n + 1;
                h = 1e-6 * max(1, abs(x_old));
                try
                    f_plus = f(x_old + h);
                    f_minus = f(x_old - h);
                    f_xold = f(x_old);
                catch
                    x1 = NaN;
                    return;
                end

                if ~isfinite(f_plus) || ~isfinite(f_minus) || ~isfinite(f_xold)
                    x1 = NaN;
                    return;
                end

                df = (f_plus - f_minus) / (2*h);

                % nếu đạo hàm quá nhỏ -> không chia được
                if abs(df) < 1e-14
                    x1 = NaN;
                    return;
                end

                x_new = x_old - f_xold / df;

                if ~isfinite(x_new)
                    x1 = NaN;
                    return;
                end

                if abs(x_new - x_old) < eps
                    x1 = x_new;
                    return;
                end

                x_old = x_new;
            end

            % không hội tụ
            x1 = NaN;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.UITable.ColumnName = {'Họ và Tên','MSSV','NV','Trạng thái'};
            app.UITable.ColumnEditable = [true true true true];

            app.UITable.Data = {
                'Nguyễn Ngọc Hùng', 23207060, 'Tích phân', 'Hoàn thành';
                'Nguyễn Ngọc Dũng',23207048, 'Nội suy',  'Hoàn thành';
                'Nguyễn Thanh An', 23207034, 'Hồi quy',  'Hoàn thành';
                'Nguyễn Trung Hiếu',23207056,'Tìm nghiệm','Hoàn thành';
                'Bùi Quốc Hưng',   23207063, 'Đạo hàm',  'Hoàn thành'
                };
        end

        % Button pushed function: batdauButton
        function batdauButtonPushed(app, event)
            syms x

            % Lấy chuỗi từ EditField và chuyển sang char
            str_raw = app.nhapphuongtrinhEditField.Value;
            if isa(str_raw,'string')
                str_raw = char(str_raw);
            end
            if ~ischar(str_raw)
                str_raw = num2str(str_raw);
            end

            % Sanitize chuỗi: thay các ký tự Unicode phổ biến
            s = str_raw;
            s = strrep(s, char(8211), '-');
            s = strrep(s, char(8722), '-');
            s = strrep(s, '×', '*');
            s = strrep(s, '·', '*');
            s = strrep(s, '÷', '/');
            s = regexprep(s, '[^\x20-\x7E]', ''); %

            % Chèn dấu nhân trong các trường hợp thường gặp
            s = regexprep(s, '(\d)\s*([A-Za-z\(])', '$1*$2');
            s = regexprep(s, '([A-Za-z\)])\s*(\d)', '$1*$2');
            s = regexprep(s, '(\d)\s*\(', '$1*(');
            s = regexprep(s, '\)\s*(\d)', ')*$1');

            % Hiện chuỗi đã làm sạch (debug)
            fprintf('Raw input: "%s"\n', str_raw);
            fprintf('Sanitized: "%s"\n', s);

            % Chuyển thành biểu thức symbolic an toàn
            try
                f_sym = str2sym(s);
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Không thể phân tích phương trình. Kiểm tra cú pháp.\nChi tiết: %s', ME.message), ...
                    'Lỗi phân tích phương trình');
                disp('Sanitized string that failed:');
                disp(s);
                return;
            end

            % chuyển thành function handle
            try
                f = matlabFunction(f_sym, 'Vars', x);
            catch ME
                uialert(app.UIFigure, sprintf('Không thể tạo hàm số từ biểu thức: %s', ME.message), 'Lỗi');
                return;
            end

            % Lấy a,b,eps
            a = app.tuaEditField.Value;
            b = app.denbEditField.Value;
            eps = app.nhapsaisochophepEditField.Value;

            % Kiểm tra eps hợp lệ
            if ~(eps > 0)
                uialert(app.UIFigure, 'Nhập sai số (epsilon) phải > 0', 'Lỗi tham số');
                return;
            end

            % Vẽ đồ thị
            try
                cla(app.UIAxes);
                fplot(app.UIAxes, f, [a b], 'LineWidth', 1.2);
                title(app.UIAxes, sprintf('f(x) = %s', s), 'Interpreter','none');
                xlabel(app.UIAxes,'x'); ylabel(app.UIAxes,'f(x)');
                grid(app.UIAxes,'on');
            catch
                uialert(app.UIFigure,'Không thể vẽ hàm trên khoảng đã cho','Lỗi vẽ');
                return;
            end

            % Chọn phương pháp
            method = app.chonphuongphaptimDropDown.Value;
            root = NaN; iter = 0;

            try
                switch method
                    case 'chia đôi'
                        % kiểm tra hợp lệ
                        try
                            fa = f(a); fb = f(b);
                        catch
                            uialert(app.UIFigure,'Giá trị f(a) hoặc f(b) không thể tính','Lỗi');
                            return;
                        end
                        if ~isfinite(fa) || ~isfinite(fb)
                            uialert(app.UIFigure,'Giá trị f(a) hoặc f(b) không hợp lệ','Lỗi');
                            return;
                        end
                        if a >= b
                            uialert(app.UIFigure,'a phải nhỏ hơn b','Lỗi');
                            return;
                        end
                        if fa * fb > 0
                            uialert(app.UIFigure,'f(a) và f(b) phải khác dấu để dùng chia đôi','Lỗi');
                            return;
                        end
                        [root, iter] = app.chiadoi(f, a, b, eps);

                    case 'lặp'
                        x0 = a;
                        [root, iter] = app.lap(f, x0, eps);

                    case 'newton'
                        x0 = a;
                        [root, iter] = app.tieptuyen(f, x0, eps);

                    otherwise
                        root = NaN; iter = 0;
                end
            catch ME
                uialert(app.UIFigure, sprintf('Lỗi khi chạy phương pháp: %s', ME.message), 'Lỗi');
                root = NaN; iter = 0;
            end

            % Hiển thị kết quả
            % luôn cập nhật số lần lặp (nếu bằng 0 sẽ hiển thị 0)
            try
                app.solanlapEditField.Value = iter;
            catch
                % nếu không thể gán (ví dụ control bị disable), bỏ qua
            end

            % Xóa mọi điểm đánh dấu nghiệm cũ
            old = findobj(app.UIAxes, 'Tag', 'RootMarker');
            if ~isempty(old)
                delete(old);
            end
            oldtxt = findobj(app.UIAxes, 'Tag', 'RootMarkerText');
            if ~isempty(oldtxt)
                delete(oldtxt);
            end

            if isnan(root)
                app.ketquanghiemEditField.Value = 0;
                uialert(app.UIFigure,'Phương pháp không hội tụ hoặc không tìm thấy nghiệm trong điều kiện cho trước','Kết quả');
            else
                app.ketquanghiemEditField.Value = root;

                % Vẽ nghiệm tại (root, f(root))
                try
                    fr = f(root);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, root, fr, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'Tag', 'RootMarker');
                    % thêm nhãn giá trị nghiệm
                    txt = sprintf('  x=%.6g\n  f=%.6g', root, fr);
                    text(app.UIAxes, root, fr, txt, 'FontSize',10, 'Color','r', 'Tag','RootMarkerText');
                    hold(app.UIAxes, 'off');
                catch
                    % nếu không vẽ được điểm (vd f(root) không tính được), bỏ qua vẽ
                end
            end
        end

        % Value changed function: chonphuongphaptimDropDown
        function chonphuongphaptimDropDownValueChanged(app, event)
            method = app.chonphuongphaptimDropDown.Value;
            if strcmp(method, 'chia đôi')
                app.denbEditField.Enable = 'on';
            else
                app.denbEditField.Enable = 'off';
                a = app.tuaEditField.Value;
                app.denbEditField.Value = a + 5;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: NewtonButton
        function NewtonButtonPushed(app, event)
            % Đọc dữ liệu nhập
            x_data = str2num(app.DulieuxEditField.Value);
            y_data = str2num(app.DulieuyEditField.Value);
            x_need = str2num(app.NhapgiatrinsyEditField.Value);

            % Kiểm tra
            if length(x_data) ~= length(y_data)
                uialert(app.UIFigure,'Số phần tử x và y phải bằng nhau','Lỗi');
                return;
            end

            % Tính bảng sai phân chia
            n = length(x_data);
            dd = y_data;
            for j = 2:n
                for i = n:-1:j
                    dd(i) = (dd(i) - dd(i-1)) / (x_data(i) - x_data(i-j+1));
                end
            end

            % Tạo đa thức dạng Symbol
            syms x;
            P = dd(n);
            for k = n-1:-1:1
                P = P * (x - x_data(k)) + dd(k);
            end

            P = expand(P);
            % Hiển thị đa thức ra EditField
            app.KQdathucnsEditField.Value = char(vpa(P,6));

            % Giá trị tại điểm cần nội suy
            result = double(subs(P, x, x_need));
            app.KetquansEditField.Value = mat2str(result,6);

            % Vẽ đồ thị
            xmin = min([x_data x_need]);
            xmax = max([x_data x_need]);
            xx = linspace(xmin, xmax, 200);
            yy = double(subs(P, x, xx));

            plot(app.UIAxes_2, xx, yy,'LineWidth',2);
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2, x_need, result, 'r*', 'MarkerSize',8, 'LineWidth',2);
            hold(app.UIAxes_2,'off');


            title(app.UIAxes_2,'Nội suy Newton');
            xlabel(app.UIAxes_2,'X');
            ylabel(app.UIAxes_2,'Y');

        end

        % Button pushed function: LagrangeButton
        function LagrangeButtonPushed(app, event)

            % Đọc dữ liệu nhập
            x_data = str2num(app.DulieuxEditField.Value);
            y_data = str2num(app.DulieuyEditField.Value);
            x_need = str2num(app.NhapgiatrinsyEditField.Value);

            % Kiểm tra
            if length(x_data) ~= length(y_data)
                uialert(app.UIFigure,'Số phần tử x và y phải bằng nhau','Lỗi');
                return;
            end

            % Lagrange Symbol
            syms x;
            n = length(x_data);
            P = 0;
            for i = 1:n
                Li = 1;
                for j = 1:n
                    if i ~= j
                        Li = Li * (x - x_data(j)) / (x_data(i) - x_data(j));
                    end
                end
                P = P + Li * y_data(i);
            end
            P = expand(P);

            % Hiển thị đa thứcs
            app.KQdathucnsEditField.Value = char(vpa(P,6));

            % Giá trị nội suy tại x_need
            result = double(subs(P, x, x_need));
            app.KetquansEditField.Value = mat2str(result,6);

            % Vẽ đồ thị
            xmin = min([x_data x_need]);
            xmax = max([x_data x_need]);
            xx = linspace(xmin, xmax, 200);
            yy = double(subs(P, x, xx));

            plot(app.UIAxes_2, xx, yy,'LineWidth',2);
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2, x_need, result, 'r*', 'MarkerSize',8, 'LineWidth',2);
            hold(app.UIAxes_2,'off');

            title(app.UIAxes_2,'Nội suy Lagrange');
            xlabel(app.UIAxes_2,'X');
            ylabel(app.UIAxes_2,'Y');
        end

        % Value changed function: KetquansEditField
        function KetquansEditFieldValueChanged(app, event)

        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)

        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            X = str2num(app.XEditField.Value);
            app.XEditField.Value = num2str(X);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            Y = str2num(app.YEditField.Value);
            app.YEditField.Value = num2str(Y);
        end

        % Button pushed function: ChyButton
        function ChyButtonPushed(app, event)
            X = str2num(app.XEditField.Value);
            Y = str2num(app.YEditField.Value);
            type = app.DropDown.Value;

            if length(X) ~= length(Y)
                uialert(app.UIFigure,'X và Y phải có cùng số phần tử','Lỗi dữ liệu');
                return;
            end

            %  Xử lý từng loại hồi quy
            switch type
                case 'Tuyến tính'
                    %  HỒI QUY TUYẾN TÍNH y = a1*x + a0
                    n = length(X);

                    sumX  = sum(X);
                    sumY  = sum(Y);
                    sumXY = sum(X .* Y);
                    sumX2 = sum(X .^ 2);

                    a1 = (n*sumXY - sumX*sumY) / (n*sumX2 - sumX^2);
                    a0 = (sumY - a1*sumX) / n;

                    % Dự đoán giá trị trên tập X
                    yfit = a1 * X + a0;

                    % Tính R2
                    st = sum((Y - mean(Y)).^2);
                    sr = sum((Y - yfit).^2);
                    r2 = 1 - sr/st;

                    % Hiển thị phương trình
                    eqStr = sprintf('y = %.4f x + %.4f', a1, a0);

                case 'Hàm mũ'
                    % Kiểm tra điều kiện hợp lệ
                    if any(X <= 0) || any(Y <= 0)
                        uialert(app.UIFigure,'X và Y phải > 0 cho mô hình y = a x^b','Lỗi');
                        return;
                    end

                    % Biến đổi log
                    LX = log(X);
                    LY = log(Y);

                    n = length(X);
                    sumLX = sum(LX);
                    sumLY = sum(LY);
                    sumLXLY = sum(LX .* LY);
                    sumLX2 = sum(LX.^2);

                    % Tính hệ số hồi quy
                    b = (n*sumLXLY - sumLX*sumLY) / (n*sumLX2 - sumLX^2);
                    A = (sumLY - b*sumLX) / n;
                    a = exp(A);  % Vì A = ln(a)

                    % Tính y theo mô hình y = a x^b
                    yfit = a * (X.^b);

                    % Tính R2
                    st = sum((Y - mean(Y)).^2);
                    sr = sum((Y - yfit).^2);
                    r2 = 1 - sr/st;

                    eqStr = sprintf('y = %.4f * x^{%.4f}', a, b);

                case 'Logarit'
                    if any(X <= 0)
                        uialert(app.UIFigure,'X phải > 0 cho hồi quy logarit','Lỗi');
                        return;
                    end

                    % Hồi quy dạng y = a + b ln(x)
                    L = log(X);
                    p = polyfit(L, Y, 1);
                    b = p(1);
                    a = p(2);

                    % Tính giá trị y hồi quy
                    yfit = a + b * log(X);

                    % R2
                    st = sum((Y - mean(Y)).^2);
                    sr = sum((Y - yfit).^2);
                    r2 = 1 - sr/st;

                    eqStr = sprintf('y = %.4f + %.4f ln(x)', a, b);
            end

            % Hiển thị kết quả
            app.KetquansEditField_2.Value = eqStr;
            app.R2EditField.Value = num2str(r2);

            % Vẽ đồ thị
            plot(app.UIAxes_3, X, Y, 'bo', 'MarkerSize', 8);
            hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, X, yfit, 'r-', 'LineWidth', 1.5);
            hold(app.UIAxes_3, 'off');
            legend(app.UIAxes_3, 'Dữ liệu', 'Hồi quy');
        end

        % Button pushed function: DonButton
        function DonButtonPushed(app, event)
            X = str2num(app.XEditField.Value);
            Y = str2num(app.YEditField.Value);
            x0 = str2double(app.GitrcndonEditField.Value);
            type = app.DropDown.Value;

            n = length(X);

            if length(X) ~= length(Y)
                uialert(app.UIFigure,'X và Y phải cùng số phần tử','Lỗi');
                return;
            end

            switch type

                % 1. Tuyến tính: y = a1*x + a0
                case 'Tuyến tính'

                    Sx  = sum(X);
                    Sy  = sum(Y);
                    Sxx = sum(X.^2);
                    Sxy = sum(X.*Y);

                    a1 = (n*Sxy - Sx*Sy) / (n*Sxx - Sx^2);
                    a0 = (Sy - a1*Sx) / n;

                    y0 = a1*x0 + a0;


                    % 2. Hàm mũ: y = a * x^b
                case 'Hàm mũ'

                    if any(X <= 0) || any(Y <= 0)
                        uialert(app.UIFigure,'X và Y phải > 0 cho mô hình y=a*x^b','Lỗi');
                        return;
                    end

                    Lx = log(X);
                    Ly = log(Y);

                    Sx  = sum(Lx);
                    Sy  = sum(Ly);
                    Sxx = sum(Lx.^2);
                    Sxy = sum(Lx.*Ly);

                    b = (n*Sxy - Sx*Sy) / (n*Sxx - Sx^2);
                    A = (Sy - b*Sx)/n;
                    a = exp(A);

                    y0 = a * (x0^b);


                    % 3. Logarit: y = a + b ln(x)
                case 'Logarit'

                    if any(X <= 0) || x0 <= 0
                        uialert(app.UIFigure,'X và x0 phải > 0 cho hàm logarit','Lỗi');
                        return;
                    end

                    L = log(X);

                    Sx  = sum(L);
                    Sy  = sum(Y);
                    Sxx = sum(L.^2);
                    Sxy = sum(L.*Y);

                    b = (n*Sxy - Sx*Sy) / (n*Sxx - Sx^2);
                    a = (Sy - b*Sx) / n;

                    y0 = a + b*log(x0);

            end

            % Hiển thị kết quả dự đoán
            app.KtqudonEditField.Value = num2str(y0);

            % Vẽ dấu * vàng
            hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3, x0, y0, '*', 'MarkerSize', 12, 'Color', [1 1 0]);
            hold(app.UIAxes_3,'off');
        end

        % Value changed function: KetquansEditField_2
        function KetquansEditField_2ValueChanged(app, event)

        end

        % Button pushed function: KtQuButton
        function KtQuButtonPushed(app, event)
            %USER FUNCTION

            %#################### USER INPUT VALUE ##########################%
            x_value = app.giatricantim.Value;
            x = str2num(strrep(app.Giatricuax.Value,',',' '));
            y = str2num(strrep(app.Giatricuay.Value,',',' '));
            h = app.GiatriH.Value;

            funcstr = app.Nhapham.Value;
            hasFunction = ~isempty(funcstr);
            if hasFunction
                f = str2func(['@(x)' funcstr]);
            else
                f = [];
            end

            TypeofError  = app.ChoSaiSo.SelectedObject;
            if (TypeofError == app.OhButton)
                errorType = "Oh";
            else
                errorType = "Oh2";
            end

            TypeofMethod  = app.ChonPhuongPhap.SelectedObject;
            if (TypeofMethod == app.TienButton)
                method = "tien";
            elseif (TypeofMethod == app.TrungTamButton)
                method = "trungtam";
            else
                method = "lui";
            end
            %#################### USER INPUT VALUE ##########################%


            %#################### MAIN  FUNCTION ############################%

            if (~isempty(f))
                %=================FUNCTION CASE=================
                switch method

                    case "tien"
                        if errorType == "Oh"
                            result = (f(x_value + h) - f(x_value)) / h;
                        else % O(h^2)cos
                            result = (-3*f(x_value) + 4*f(x_value + h) - f(x_value + 2*h)) / (2*h);
                        end

                    case "trungtam"
                        if errorType == "Oh"
                            result = (f(x_value + h) - f(x_value - h)) / (2*h);
                        else % O(h^2)
                            result = (-f(x_value + 2*h) + 8*f(x_value + h) - 8*f(x_value - h) + f(x_value - 2*h)) / (12*h);
                        end

                    case "lui"
                        if errorType == "Oh"
                            result = (f(x_value) - f(x_value - h)) / h;
                        else % O(h^2)
                            result = (3*f(x_value) - 4*f(x_value - h) + f(x_value - 2*h)) / (2*h);
                        end
                end

            else

                %=============NUMBERIC CASE====================
                [~, idx] = min(abs(x - x_value));

                if method == "tien"
                    if idx >= length(x)
                        result = NaN; % không thể tiến
                    else
                        result = (y(idx+1) - y(idx)) / (x(idx+1) - x(idx));
                    end

                elseif method == "lui"
                    if idx <= 1
                        result = NaN; % không thể lùi
                    else
                        result = (y(idx) - y(idx-1)) / (x(idx) - x(idx-1));
                    end

                else % trung tâm
                    if idx <= 1 || idx >= length(x)
                        result = NaN; % không thể trung tâm
                    else
                        result = (y(idx+1) - y(idx-1)) / (x(idx+1) - x(idx-1));
                    end
                end
            end

            %================ HIỆN KẾT QUẢ ================%
            app.Ketqua.Value = result;


            %#################### MAIN FUNCTION #############################%
        end

        % Button pushed function: PPhinhthangButton
        function PPhinhthangButtonPushed(app, event)
            [x, y, mode] = app.getData();

            % Nếu x, y rỗng thì thôi
            if isempty(x) || isempty(y)
                return;
            end

            % x,y đã có → dùng trapz trực tiếp
            result = trapz(x, y);

            app.KtquaEditField.Value = num2str(result);
        end

        % Button pushed function: PPsimpson13Button
        function PPsimpson13ButtonPushed(app, event)
            [x, y, mode] = app.getData();

            if isempty(x) || isempty(y)
                %app.KtquaEditField.Value = 'Lỗi dữ liệu';
                return;
            end

            if mode == "func"
                N = str2double(app.NhpNEditField.Value);
                if isnan(N) || N <= 0 || floor(N) ~= N
                    app.KtquaEditField.Value = 'N phải là số chẵn';
                    return;
                end
            end

            try
                result = app.simpson13(x, y);
                app.KtquaEditField.Value = num2str(result);
            catch ME
                app.KtquaEditField.Value = ME.message;
            end

        end

        % Button pushed function: PPsimpson38Button
        function PPsimpson38ButtonPushed(app, event)
            [x, y, mode] = app.getData();

            if isempty(x) || isempty(y)
                %    app.KtquaEditField.Value = 'Lỗi dữ liệu';
                return;
            end

            if mode == "func"
                N = str2double(app.NhpNEditField.Value);
                if isnan(N) || N <= 0 || floor(N) ~= N
                    app.KtquaEditField.Value = 'N phải là số chia hết cho 3';
                    return;
                end
            end

            try
                result = app.simpson38(x, y);
                app.KtquaEditField.Value = num2str(result);
            catch ME
                app.KtquaEditField.Value = ME.message;
            end
        end

        % Value changed function: NhpcntinhtichphnEditField
        function NhpcntinhtichphnEditFieldValueChanged(app, event)


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);

            % Nếu sửa cột Họ và Tên
            if col == 1
                if ~ischar(event.NewData) && ~isstring(event.NewData)
                    uialert(app.UIFigure,'Họ và tên phải là chữ','Lỗi');
                    app.UITable.Data{row,col} = event.PreviousData;
                end
            end

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.NPHNGPHPTNHLabel = uilabel(app.GiithiunhmTab);
            app.NPHNGPHPTNHLabel.Text = 'ĐỒ ÁN PHƯƠNG PHÁP TÍNH';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Trường_Đại_học_Khoa_học_Tự_nhiên,_Đại_học_Quốc_gia_Thành_phố_Hồ_Chí_Minh.svg.png');
            app.GvThSHUNHQUCTHNHLabel = uilabel(app.GiithiunhmTab);
            app.GvThSHUNHQUCTHNHLabel.Text = 'Gv: Th.S HUỲNH QUỐC THỊNH';
            app.UITable = uitable(app.GiithiunhmTab);
            app.UITable.ColumnName = {'HỌ VÀ TÊN'; 'MSSV'; 'NV'; 'Trạng thái'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.TmNghimTab = uitab(app.TabGroup);
            app.TmNghimTab.Title = 'Tìm Nghiệm';
            app.UIAxes = uiaxes(app.TmNghimTab);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.KhongphnlyABLabel = uilabel(app.TmNghimTab);
            app.KhongphnlyABLabel.Text = 'Khoảng phân ly [A B] ';
            app.chonphuongphaptimDropDown = uidropdown(app.TmNghimTab);
            app.chonphuongphaptimDropDown.Items = {'chia đôi', 'lặp', 'newton'};
            app.chonphuongphaptimDropDown.ValueChangedFcn = createCallbackFcn(app, @chonphuongphaptimDropDownValueChanged, true);
            app.chonphuongphaptimDropDown.Value = 'chia đôi';
            app.batdauButton = uibutton(app.TmNghimTab, 'push');
            app.batdauButton.ButtonPushedFcn = createCallbackFcn(app, @batdauButtonPushed, true);
            app.batdauButton.Text = 'bắt đầu ';
            app.BiTonTmNghimLabel = uilabel(app.TmNghimTab);
            app.BiTonTmNghimLabel.Text = 'Bài Toán Tìm Nghiệm ';
            app.NhpphngtrnhLabel = uilabel(app.TmNghimTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình';
            app.nhapphuongtrinhEditField = uieditfield(app.TmNghimTab, 'text');
            app.nhapphuongtrinhEditField.Value = 'x^3 + x -1';
            app.ALabel = uilabel(app.TmNghimTab);
            app.ALabel.Text = 'A';
            app.tuaEditField = uieditfield(app.TmNghimTab, 'numeric');
            app.BLabel = uilabel(app.TmNghimTab);
            app.BLabel.Text = 'B';
            app.denbEditField = uieditfield(app.TmNghimTab, 'numeric');
            app.denbEditField.Value = 1;
            app.SaischophpLabel = uilabel(app.TmNghimTab);
            app.SaischophpLabel.Text = 'Sai số cho phép';
            app.nhapsaisochophepEditField = uieditfield(app.TmNghimTab, 'numeric');
            app.nhapsaisochophepEditField.Value = 0.002;
            app.KtquLabel = uilabel(app.TmNghimTab);
            app.KtquLabel.Text = 'Kết quả';
            app.ketquanghiemEditField = uieditfield(app.TmNghimTab, 'numeric');
            app.SlnlpLabel = uilabel(app.TmNghimTab);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.solanlapEditField = uieditfield(app.TmNghimTab, 'numeric');
            app.NiSuyTab = uitab(app.TabGroup);
            app.NiSuyTab.Title = 'Nội Suy';
            app.UIAxes_2 = uiaxes(app.NiSuyTab);
            title(app.UIAxes_2, 'Đồ thị')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.ChonPPnsLabel = uilabel(app.NiSuyTab);
            app.ChonPPnsLabel.Text = 'Chọn phương pháp';
            app.NewtonButton = uibutton(app.NiSuyTab, 'push');
            app.NewtonButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonButtonPushed, true);
            app.NewtonButton.Text = ' Newton';
            app.LagrangeButton = uibutton(app.NiSuyTab, 'push');
            app.LagrangeButton.ButtonPushedFcn = createCallbackFcn(app, @LagrangeButtonPushed, true);
            app.LagrangeButton.Text = 'Lagrange';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KQdathucnsEditField = uieditfield(app.NiSuyTab, 'text');
            app.NhpliuxLabel = uilabel(app.NiSuyTab);
            app.NhpliuxLabel.Text = 'Nhập đữ liệu x';
            app.DulieuxEditField = uieditfield(app.NiSuyTab, 'text');
            app.DulieuxEditField.Value = '0.1 0.2 0.3 0.4';
            app.NhpliuxLabel_2 = uilabel(app.NiSuyTab);
            app.NhpliuxLabel_2.Text = 'Nhập đữ liệu y';
            app.DulieuyEditField = uieditfield(app.NiSuyTab, 'text');
            app.DulieuyEditField.Value = '0.09983 0.19867 0.29552 0.38942';
            app.KtqunisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KetquansEditField = uieditfield(app.NiSuyTab, 'text');
            app.KetquansEditField.ValueChangedFcn = createCallbackFcn(app, @KetquansEditFieldValueChanged, true);
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhapgiatrinsyEditField = uieditfield(app.NiSuyTab, 'text');
            app.NhapgiatrinsyEditField.Value = '0.14 0.46';
            app.BiTonNiSuyLabel = uilabel(app.NiSuyTab);
            app.BiTonNiSuyLabel.Text = 'Bài Toán Nội Suy ';
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.UIAxes_3 = uiaxes(app.HiQuyTab);
            title(app.UIAxes_3, 'Đồ thị')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ChyButton = uibutton(app.HiQuyTab, 'push');
            app.ChyButton.ButtonPushedFcn = createCallbackFcn(app, @ChyButtonPushed, true);
            app.ChyButton.Text = 'Chạy';
            app.DonButton = uibutton(app.HiQuyTab, 'push');
            app.DonButton.ButtonPushedFcn = createCallbackFcn(app, @DonButtonPushed, true);
            app.DonButton.Text = 'Dự đoán';
            app.XEditFieldLabel = uilabel(app.HiQuyTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.HiQuyTab, 'text');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = '1 2 3 4 5 6 7 8 9';
            app.YEditFieldLabel = uilabel(app.HiQuyTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.HiQuyTab, 'text');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = '1 1.5 2 3 4 5 8 10 13';
            app.DropDown = uidropdown(app.HiQuyTab);
            app.DropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.DropDown.Value = 'Tuyến tính';
            app.KtquhiquyLabel = uilabel(app.HiQuyTab);
            app.KtquhiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.KetquansEditField_2 = uieditfield(app.HiQuyTab, 'text');
            app.KetquansEditField_2.ValueChangedFcn = createCallbackFcn(app, @KetquansEditField_2ValueChanged, true);
            app.GitrcndonEditFieldLabel = uilabel(app.HiQuyTab);
            app.GitrcndonEditFieldLabel.Text = 'Giá trị cần dự đoán';
            app.GitrcndonEditField = uieditfield(app.HiQuyTab, 'text');
            app.KtqudonEditFieldLabel = uilabel(app.HiQuyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.HiQuyTab, 'text');
            app.R2EditFieldLabel = uilabel(app.HiQuyTab);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.HiQuyTab, 'text');
            app.BiTonHiQuyLabel = uilabel(app.HiQuyTab);
            app.BiTonHiQuyLabel.Text = 'Bài Toán Hồi Quy ';
            app.oHmTab = uitab(app.TabGroup);
            app.oHmTab.Title = 'Đạo Hàm';
            app.ChonPhuongPhap = uibuttongroup(app.oHmTab);
            app.ChonPhuongPhap.Title = 'Chọn Phương Pháp';
            app.TienButton = uitogglebutton(app.ChonPhuongPhap);
            app.TienButton.Text = 'Tiến';
            app.TienButton.Value = true;
            app.TrungTamButton = uitogglebutton(app.ChonPhuongPhap);
            app.TrungTamButton.Text = 'Trung Tâm ';
            app.LuiButton = uitogglebutton(app.ChonPhuongPhap);
            app.LuiButton.Text = 'Lùi';
            app.ChoSaiSo = uibuttongroup(app.oHmTab);
            app.ChoSaiSo.Title = 'Sai số bước nhảy';
            app.OhButton = uitogglebutton(app.ChoSaiSo);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.Oh2Button = uitogglebutton(app.ChoSaiSo);
            app.Oh2Button.Text = 'O(h^2)';
            app.KtQuButton = uibutton(app.oHmTab, 'push');
            app.KtQuButton.ButtonPushedFcn = createCallbackFcn(app, @KtQuButtonPushed, true);
            app.KtQuButton.Text = 'Kết Quả';
            app.Ketqua = uieditfield(app.oHmTab, 'numeric');
            app.HocLabel = uilabel(app.oHmTab);
            app.HocLabel.Text = 'Hoặc';
            app.GitrcntnhEditFieldLabel = uilabel(app.oHmTab);
            app.GitrcntnhEditFieldLabel.Text = 'Giá trị cần tính';
            app.giatricantim = uieditfield(app.oHmTab, 'numeric');
            app.giatricantim.Value = 0.5;
            app.NhpBchEditFieldLabel = uilabel(app.oHmTab);
            app.NhpBchEditFieldLabel.Text = 'Nhập Bước h';
            app.GiatriH = uieditfield(app.oHmTab, 'numeric');
            app.GiatriH.Value = 0.25;
            app.NhpHmsEditFieldLabel = uilabel(app.oHmTab);
            app.NhpHmsEditFieldLabel.Text = 'Nhập Hàm số';
            app.Nhapham = uieditfield(app.oHmTab, 'text');
            app.Nhapham.Value = '-0.1*x^4 - 0.15*x^3 - 0.5*x^2 - 0.25*x + 1.2';
            app.NhpdliuxEditFieldLabel = uilabel(app.oHmTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.Giatricuax = uieditfield(app.oHmTab, 'text');
            app.Giatricuax.Value = '0.1 0.2 0.3 0.4';
            app.NhpdliuyEditFieldLabel = uilabel(app.oHmTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Giatricuay = uieditfield(app.oHmTab, 'text');
            app.Giatricuay.Value = '0.09983 0.19867 0.29552 0.38942';
            app.BiTonoHmLabel = uilabel(app.oHmTab);
            app.BiTonoHmLabel.Text = 'Bài Toán Đạo Hàm ';
            app.VDNhpdliux01020304nhpdliuy009983019867029552038942Gitrcntnh02NnsdngSaiphntrungtmOh2SaiphntinliOhLabel = uilabel(app.oHmTab);
            app.VDNhpdliux01020304nhpdliuy009983019867029552038942Gitrcntnh02NnsdngSaiphntrungtmOh2SaiphntinliOhLabel.Text = {'VÍ DỤ:'; 'Nhập dữ liệu x: [0.1 0.2 0.3 0.4]'; 'nhập dữ liệu y: [0.09983 0.19867 0.29552 0.38942]'; 'Giá trị cần tính: 0.2'; 'Nên sử dụng Sai phân trung tâm ( O(h^2) ), Sai phân tiến lùi O(h)'};
            app.VDNhphms01x4015x305x2025x12Bcnhy025Gitrcntnh05NnsdngOh2Label = uilabel(app.oHmTab);
            app.VDNhphms01x4015x305x2025x12Bcnhy025Gitrcntnh05NnsdngOh2Label.Text = {'VÍ DỤ:'; 'Nhập hàm số: -0.1*x^4 - 0.15*x^3 - 0.5*x^2 - 0.25*x + 1.2'; 'Bước nhảy: 0.25'; 'Giá trị cần tính: 0.5'; 'Nên sử dụng ( O(h^2) )'};
            app.LuHyxa1trong2trckhisdngvthayigitrcntnhvbcnhyhLabel = uilabel(app.oHmTab);
            app.LuHyxa1trong2trckhisdngvthayigitrcntnhvbcnhyhLabel.Text = {'Lưu ý: Hãy xóa 1 trong 2 trước khi sử dụng và '; '          thay đổi giá trị cần tính và bước nhảy h'};
            app.TchPhnTab = uitab(app.TabGroup);
            app.TchPhnTab.Title = 'Tích Phân';
            app.HocLabel_2 = uilabel(app.TchPhnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.PPhinhthangButton = uibutton(app.TchPhnTab, 'push');
            app.PPhinhthangButton.ButtonPushedFcn = createCallbackFcn(app, @PPhinhthangButtonPushed, true);
            app.PPhinhthangButton.Text = 'PP hình thang';
            app.PPsimpson13Button = uibutton(app.TchPhnTab, 'push');
            app.PPsimpson13Button.ButtonPushedFcn = createCallbackFcn(app, @PPsimpson13ButtonPushed, true);
            app.PPsimpson13Button.Text = 'PP simpson 1/3';
            app.PPsimpson38Button = uibutton(app.TchPhnTab, 'push');
            app.PPsimpson38Button.ButtonPushedFcn = createCallbackFcn(app, @PPsimpson38ButtonPushed, true);
            app.PPsimpson38Button.Text = 'PP simpson 3/8';
            app.Label = uilabel(app.TchPhnTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.TchPhnTab);
            app.Label_2.Text = '';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.TchPhnTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x ';
            app.NhpdliuxEditField = uieditfield(app.TchPhnTab, 'text');
            app.NhpdliuxEditField.Value = '1 2 3 4 5 6 7';
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.TchPhnTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.TchPhnTab, 'text');
            app.NhpdliuyEditField.Value = '3 5 7 9 11 13 15';
            app.NhphamsEditFieldLabel = uilabel(app.TchPhnTab);
            app.NhphamsEditFieldLabel.Text = 'Nhập hàm số';
            app.NhphamsEditField = uieditfield(app.TchPhnTab, 'text');
            app.NhphamsEditField.Value = 'x.^3 + x -1';
            app.NhpcntinhtichphnEditFieldLabel = uilabel(app.TchPhnTab);
            app.NhpcntinhtichphnEditFieldLabel.Text = 'Nhập cận để tính tích phân ';
            app.NhpcntinhtichphnEditField = uieditfield(app.TchPhnTab, 'text');
            app.NhpcntinhtichphnEditField.ValueChangedFcn = createCallbackFcn(app, @NhpcntinhtichphnEditFieldValueChanged, true);
            app.NhpcntinhtichphnEditField.Value = '1 5';
            app.NhpNEditFieldLabel = uilabel(app.TchPhnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhpNEditField = uieditfield(app.TchPhnTab, 'text');
            app.NhpNEditField.Value = '4';
            app.KtquaEditFieldLabel = uilabel(app.TchPhnTab);
            app.KtquaEditFieldLabel.Text = 'Kết quả';
            app.KtquaEditField = uieditfield(app.TchPhnTab, 'text');
            app.BiTonTchPhnLabel = uilabel(app.TchPhnTab);
            app.BiTonTchPhnLabel.Text = 'Bài Toán Tích Phân ';
            app.NoteKhinhpdliudangxvaythinhpNkhngcotacdungviNctinhtngslngimx1ivinhphamsthiNlabtbucvchngtrnhcnbitsontnhtchphntheotngphngphapcuthKhisdngpphinhthangthNphisiuln100Khisdungppsimpson38thiNphaichiahtcho3Khisdungppsimpson18thiNphaichnNumunhmscnhnhocmthphinhnmngLabel = uilabel(app.TchPhnTab);
            app.NoteKhinhpdliudangxvaythinhpNkhngcotacdungviNctinhtngslngimx1ivinhphamsthiNlabtbucvchngtrnhcnbitsontnhtchphntheotngphngphapcuthKhisdngpphinhthangthNphisiuln100Khisdungppsimpson38thiNphaichiahtcho3Khisdungppsimpson18thiNphaichnNumunhmscnhnhocmthphinhnmngLabel.Text = {'Note: Khi nhập dữ liệu dạng x và y thì ô nhập N không có tác dụng'; 'vì N được tính tự động = số lượng điểm x -  1'; '- Đối với nhập hàm số thì N là bắt buộc vì chương trình cần biết số'; 'đoạn, để tính tích phân theo từng phương pháp cụ thể'; '- Khi sử dụng pp hinhthang thì N phải siêu lớn >100'; '- Khi sử dụng pp simpson'; ' 3/8 thì N phải chia '; 'hết cho 3'; '- Khi sử dụng pp simpson'; ' 1/8 thì N phải chẵn'; '- Nếu muốn hàm số có nhân hoặc mũ thì phải nhân mảng .* .^'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end