classdef DaoHamapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        VDNhphmsx32x1Bcnhy0001Gitrcntnh3Label  matlab.ui.control.Label
        VDNhpdliux0102030405nhpdliuy34567Gitrcntnh03535Label  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Giatricuay                matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2  matlab.ui.control.Label
        Giatricuax                matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2  matlab.ui.control.Label
        HocLabel                  matlab.ui.control.Label
        Ketqua                    matlab.ui.control.NumericEditField
        KtQuButton                matlab.ui.control.Button
        ChoSaiSo                  matlab.ui.container.ButtonGroup
        Oh2Button                 matlab.ui.control.ToggleButton
        OhButton                  matlab.ui.control.ToggleButton
        Nhapham                   matlab.ui.control.EditField
        NhpHmsEditFieldLabel      matlab.ui.control.Label
        GiatriH                   matlab.ui.control.NumericEditField
        NhpBchEditFieldLabel      matlab.ui.control.Label
        giatricantim              matlab.ui.control.NumericEditField
        GitrcntnhEditFieldLabel   matlab.ui.control.Label
        ChonPhuongPhap            matlab.ui.container.ButtonGroup
        LuiButton                 matlab.ui.control.ToggleButton
        TrungTamButton            matlab.ui.control.ToggleButton
        TienButton                matlab.ui.control.ToggleButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KtQuButton
        function Result_Func(app, event)
         %USER FUNCTION 

         %#################### USER INPUT VALUE ##########################%
         x_value = app.giatricantim.Value;
         x = str2num(strrep(app.Giatricuax.Value,',',' '));
         y = str2num(strrep(app.Giatricuay.Value,',',' '));
         h = app.GiatriH.Value;
         
         funcstr = app.Nhapham.Value;
         hasFunction = ~isempty(funcstr);
         if hasFunction
           f = str2func(['@(x)' funcstr]);
         else
           f = [];
         end 

         TypeofError  = app.ChoSaiSo.SelectedObject;
         if (TypeofError == app.OhButton)
             errorType = "Oh";
         else
             errorType = "Oh2";
         end 

         TypeofMethod  = app.ChonPhuongPhap.SelectedObject;
         if (TypeofMethod == app.TienButton)
             method = "tien";
         elseif (TypeofMethod == app.TrungTamButton)
             method = "trungtam";
         else 
             method = "lui";
         end 
         %#################### USER INPUT VALUE ##########################%


         %#################### MAIN  FUNCTION ############################%
        
         if (~isempty(f))           
           %=================FUNCTION CASE=================
          switch method
        
          case "tien"
            if errorType == "Oh"
                result = (f(x_value + h) - f(x_value)) / h;
            else % O(h^2)cos
                result = (-3*f(x_value) + 4*f(x_value + h) - f(x_value + 2*h)) / (2*h);
            end

          case "trungtam"
            if errorType == "Oh"
                result = (f(x_value + h) - f(x_value - h)) / (2*h);
            else % O(h^2)
                result = (-f(x_value + 2*h) + 8*f(x_value + h) - 8*f(x_value - h) + f(x_value - 2*h)) / (12*h);
            end

          case "lui"
            if errorType == "Oh"
                result = (f(x_value) - f(x_value - h)) / h;
            else % O(h^2)
                result = (3*f(x_value) - 4*f(x_value - h) + f(x_value - 2*h)) / (2*h);
            end
         end

         else

         %=============NUMBERIC CASE====================
         [~, idx] = min(abs(x - x_value));

         if method == "tien"
           if idx >= length(x)
            result = NaN; % không thể tiến
           else
            result = (y(idx+1) - y(idx)) / (x(idx+1) - x(idx));
           end
        
        elseif method == "lui"
         if idx <= 1
            result = NaN; % không thể lùi
         else
            result = (y(idx) - y(idx-1)) / (x(idx) - x(idx-1));
         end

       else % trung tâm
        if idx <= 1 || idx >= length(x)
            result = NaN; % không thể trung tâm
        else
            result = (y(idx+1) - y(idx-1)) / (x(idx+1) - x(idx-1));
        end
       end
      end

      %================ HIỆN KẾT QUẢ ================%
      app.Ketqua.Value = result;


      %#################### MAIN FUNCTION #############################%
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChonPhuongPhap = uibuttongroup(app.UIFigure);
            app.ChonPhuongPhap.Title = 'Chọn Phương Pháp';
            app.TienButton = uitogglebutton(app.ChonPhuongPhap);
            app.TienButton.Text = 'Tiến';
            app.TienButton.Value = true;
            app.TrungTamButton = uitogglebutton(app.ChonPhuongPhap);
            app.TrungTamButton.Text = 'Trung Tâm ';
            app.LuiButton = uitogglebutton(app.ChonPhuongPhap);
            app.LuiButton.Text = 'Lùi';
            app.GitrcntnhEditFieldLabel = uilabel(app.UIFigure);
            app.GitrcntnhEditFieldLabel.Text = 'Giá trị cần tính';
            app.giatricantim = uieditfield(app.UIFigure, 'numeric');
            app.NhpBchEditFieldLabel = uilabel(app.UIFigure);
            app.NhpBchEditFieldLabel.Text = 'Nhập Bước h';
            app.GiatriH = uieditfield(app.UIFigure, 'numeric');
            app.NhpHmsEditFieldLabel = uilabel(app.UIFigure);
            app.NhpHmsEditFieldLabel.Text = 'Nhập Hàm số';
            app.Nhapham = uieditfield(app.UIFigure, 'text');
            app.ChoSaiSo = uibuttongroup(app.UIFigure);
            app.ChoSaiSo.Title = 'Sai số bước nhảy';
            app.OhButton = uitogglebutton(app.ChoSaiSo);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.Oh2Button = uitogglebutton(app.ChoSaiSo);
            app.Oh2Button.Text = 'O(h^2)';
            app.KtQuButton = uibutton(app.UIFigure, 'push');
            app.KtQuButton.ButtonPushedFcn = createCallbackFcn(app, @Result_Func, true);
            app.KtQuButton.Text = 'Kết Quả';
            app.Ketqua = uieditfield(app.UIFigure, 'numeric');
            app.HocLabel = uilabel(app.UIFigure);
            app.HocLabel.Text = 'Hoặc';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.UIFigure);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.Giatricuax = uieditfield(app.UIFigure, 'text');
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.UIFigure);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.Giatricuay = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.VDNhpdliux0102030405nhpdliuy34567Gitrcntnh03535Label = uilabel(app.UIFigure);
            app.VDNhpdliux0102030405nhpdliuy34567Gitrcntnh03535Label.Text = {'VÍ DỤ:'; 'Nhập dữ liệu x: [0.1 0,2 0,3 0.4 0.5]'; 'nhập dữ liệu y: [3 4 5 6 7]'; 'Giá trị cần tính: 0.3535'};
            app.VDNhphmsx32x1Bcnhy0001Gitrcntnh3Label = uilabel(app.UIFigure);
            app.VDNhphmsx32x1Bcnhy0001Gitrcntnh3Label.Text = {'VÍ DỤ:'; 'Nhập hàm số: x^3 + 2*x - 1'; 'Bước nhảy: 0.001'; 'Giá trị cần tính: 3'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DaoHamapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end