classdef timnghiem2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BiTonTmNghimLabel            matlab.ui.control.Label
        solanlapEditField            matlab.ui.control.NumericEditField
        slnlpEditFieldLabel          matlab.ui.control.Label
        ketquanghiemEditField        matlab.ui.control.NumericEditField
        ktqunghimEditFieldLabel      matlab.ui.control.Label
        batdauButton                 matlab.ui.control.Button
        chonphuongphaptimDropDown    matlab.ui.control.DropDown
        chnphngphptmDropDownLabel    matlab.ui.control.Label
        nhapsaisochophepEditField    matlab.ui.control.NumericEditField
        nhpsaischophpEditFieldLabel  matlab.ui.control.Label
        khongphnlynghimLabel         matlab.ui.control.Label
        denbEditField                matlab.ui.control.NumericEditField
        nbEditFieldLabel             matlab.ui.control.Label
        tuaEditField                 matlab.ui.control.NumericEditField
        taEditFieldLabel             matlab.ui.control.Label
        nhapphuongtrinhEditField     matlab.ui.control.EditField
        nhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function [c,n] = chiadoi(app, f, a, b, eps)
            n = 0;
            % Basic checks
            try
                fa = f(a); fb = f(b);
            catch
                c = NaN; n = 0; return;
            end
            if ~isfinite(fa) || ~isfinite(fb)
                c = NaN; n = 0; return;
            end
            if fa * fb > 0
                c = NaN; n = 0; return;
            end

            maxIter = 10000;
            while true
                n = n + 1;
                c = (a + b) / 2;
                fc = f(c);

                % Nếu giá trị f(c) đủ nhỏ thì coi là nghiệm
                if ~isnan(fc) && abs(fc) < eps
                    return;
                end

                % Dừng theo khoảng [a,b]
                if (b - a) / 2 < eps
                    return;
                end

                if fa * fc < 0
                    b = c;
                    fb = fc;
                else
                    a = c;
                    fa = fc;
                end

                if n >= maxIter
                    c = NaN;
                    return;
                end
            end
        end

        function [x1,n] = lap(app, f, x0, eps)
            n = 0;
            maxIter = 10000;
            x_old = x0;

            % Try a list of relaxations (lambda) to improve chance hội tụ
            lambda_list = [1, 0.8, 0.5, 0.2, 0.1, 0.05, 0.02, 0.01];

            for L = lambda_list
                x_old = x0;
                n_local = 0;
                while true
                    n_local = n_local + 1;
                    % fixed-point style: x_new = x_old - L * f(x_old)
                    % (L = 1 would be plain Newton-like single-step if f ~ derivative)
                    try
                        fx = f(x_old);
                    catch
                        break;
                    end

                    x_new = x_old - L * fx;

                    % kiểm tra hội tụ
                    if ~isfinite(x_new)
                        break;
                    end

                    if abs(x_new - x_old) < eps
                        x1 = x_new;
                        n = n_local;
                        return;
                    end

                    x_old = x_new;

                    if n_local >= maxIter
                        break;
                    end
                end
            end

            % Nếu không hội tụ
            x1 = NaN;
            n = 0;
        end


        function [x1,n] = tieptuyen(app, f, x0, eps)
            n = 0;
            maxIter = 1000;
            x_old = x0;

            for k = 1:maxIter
                n = n + 1;

                % numeric derivative central difference
                h = 1e-6 * max(1, abs(x_old));
                try
                    f_plus = f(x_old + h);
                    f_minus = f(x_old - h);
                    f_xold = f(x_old);
                catch
                    x1 = NaN;
                    return;
                end

                if ~isfinite(f_plus) || ~isfinite(f_minus) || ~isfinite(f_xold)
                    x1 = NaN;
                    return;
                end

                df = (f_plus - f_minus) / (2*h);

                % nếu đạo hàm quá nhỏ -> không chia được
                if abs(df) < 1e-14
                    x1 = NaN;
                    return;
                end

                x_new = x_old - f_xold / df;

                if ~isfinite(x_new)
                    x1 = NaN;
                    return;
                end

                if abs(x_new - x_old) < eps
                    x1 = x_new;
                    return;
                end

                x_old = x_new;
            end

            % không hội tụ
            x1 = NaN;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: batdauButton
        function batdauButtonPushed(app, event)
            syms x

            % --- Lấy chuỗi từ EditField và chuyển sang char ---
            str_raw = app.nhapphuongtrinhEditField.Value;
            if isa(str_raw,'string')
                str_raw = char(str_raw);
            end
            if ~ischar(str_raw)
                str_raw = num2str(str_raw);
            end

            % --- Sanitize chuỗi: thay các ký tự Unicode phổ biến ---
            s = str_raw;
            s = strrep(s, char(8211), '-');  % en-dash → minus
            s = strrep(s, char(8722), '-');  % minus sign → ascii -
            s = strrep(s, '×', '*');         % multiplication sign
            s = strrep(s, '·', '*');         % middle dot
            s = strrep(s, '÷', '/');         % division sign
            s = regexprep(s, '[^\x20-\x7E]', ''); % remove non-printable

            % Chèn dấu nhân trong các trường hợp thường gặp
            s = regexprep(s, '(\d)\s*([A-Za-z\(])', '$1*$2');
            s = regexprep(s, '([A-Za-z\)])\s*(\d)', '$1*$2');
            s = regexprep(s, '(\d)\s*\(', '$1*(');
            s = regexprep(s, '\)\s*(\d)', ')*$1');

            % Hiện chuỗi đã làm sạch (debug)
            fprintf('Raw input: "%s"\n', str_raw);
            fprintf('Sanitized: "%s"\n', s);

            % Chuyển thành biểu thức symbolic an toàn
            try
                f_sym = str2sym(s);
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Không thể phân tích phương trình. Kiểm tra cú pháp.\nChi tiết: %s', ME.message), ...
                    'Lỗi phân tích phương trình');
                disp('Sanitized string that failed:');
                disp(s);
                return;
            end

            % chuyển thành function handle
            try
                f = matlabFunction(f_sym, 'Vars', x);
            catch ME
                uialert(app.UIFigure, sprintf('Không thể tạo hàm số từ biểu thức: %s', ME.message), 'Lỗi');
                return;
            end

            % Lấy a,b,eps
            a = app.tuaEditField.Value;
            b = app.denbEditField.Value;
            eps = app.nhapsaisochophepEditField.Value;

            % Kiểm tra eps hợp lệ
            if ~(eps > 0)
                uialert(app.UIFigure, 'Nhập sai số (epsilon) phải > 0', 'Lỗi tham số');
                return;
            end

            % Vẽ đồ thị
            try
                cla(app.UIAxes);
                fplot(app.UIAxes, f, [a b], 'LineWidth', 1.2);
                title(app.UIAxes, sprintf('f(x) = %s', s), 'Interpreter','none');
                xlabel(app.UIAxes,'x'); ylabel(app.UIAxes,'f(x)');
                grid(app.UIAxes,'on');
            catch
                uialert(app.UIFigure,'Không thể vẽ hàm trên khoảng đã cho','Lỗi vẽ');
                return;
            end

            % Chọn phương pháp
            method = app.chonphuongphaptimDropDown.Value;
            root = NaN; iter = 0;

            try
                switch method
                    case 'chia đôi'
                        % kiểm tra hợp lệ
                        try
                            fa = f(a); fb = f(b);
                        catch
                            uialert(app.UIFigure,'Giá trị f(a) hoặc f(b) không thể tính','Lỗi');
                            return;
                        end
                        if ~isfinite(fa) || ~isfinite(fb)
                            uialert(app.UIFigure,'Giá trị f(a) hoặc f(b) không hợp lệ','Lỗi');
                            return;
                        end
                        if a >= b
                            uialert(app.UIFigure,'a phải nhỏ hơn b','Lỗi');
                            return;
                        end
                        if fa * fb > 0
                            uialert(app.UIFigure,'f(a) và f(b) phải khác dấu để dùng chia đôi','Lỗi');
                            return;
                        end
                        [root, iter] = app.chiadoi(f, a, b, eps);

                    case 'lặp'
                        x0 = a;
                        [root, iter] = app.lap(f, x0, eps);

                    case 'newton'
                        x0 = a;
                        [root, iter] = app.tieptuyen(f, x0, eps);

                    otherwise
                        root = NaN; iter = 0;
                end
            catch ME
                uialert(app.UIFigure, sprintf('Lỗi khi chạy phương pháp: %s', ME.message), 'Lỗi');
                root = NaN; iter = 0;
            end

            % Hiển thị kết quả
            % luôn cập nhật số lần lặp (nếu bằng 0 sẽ hiển thị 0)
            try
                app.solanlapEditField.Value = iter;
            catch
                % nếu không thể gán (ví dụ control bị disable), bỏ qua
            end

            % Xóa mọi điểm đánh dấu nghiệm cũ
            old = findobj(app.UIAxes, 'Tag', 'RootMarker');
            if ~isempty(old)
                delete(old);
            end
            oldtxt = findobj(app.UIAxes, 'Tag', 'RootMarkerText');
            if ~isempty(oldtxt)
                delete(oldtxt);
            end

            if isnan(root)
                app.ketquanghiemEditField.Value = 0;
                uialert(app.UIFigure,'Phương pháp không hội tụ hoặc không tìm thấy nghiệm trong điều kiện cho trước','Kết quả');
            else
                app.ketquanghiemEditField.Value = root;

                % Vẽ nghiệm tại (root, f(root))
                try
                    fr = f(root);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, root, fr, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'Tag', 'RootMarker');
                    % thêm nhãn giá trị nghiệm
                    txt = sprintf('  x=%.6g\n  f=%.6g', root, fr);
                    text(app.UIAxes, root, fr, txt, 'FontSize',10, 'Color','r', 'Tag','RootMarkerText');
                    hold(app.UIAxes, 'off');
                catch
                    % nếu không vẽ được điểm (vd f(root) không tính được), bỏ qua vẽ
                end
            end

        end

        % Value changed function: chonphuongphaptimDropDown
        function chonphuongphaptimDropDownValueChanged2(app, event)
            method = app.chonphuongphaptimDropDown.Value;
            if strcmp(method, 'chia đôi')
                app.denbEditField.Enable = 'on';
            else
                app.denbEditField.Enable = 'off';
                a = app.tuaEditField.Value;
                app.denbEditField.Value = a + 5;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.nhpphngtrnhEditFieldLabel = uilabel(app.UIFigure);
            app.nhpphngtrnhEditFieldLabel.Text = 'nhập phương trình';
            app.nhapphuongtrinhEditField = uieditfield(app.UIFigure, 'text');
            app.nhapphuongtrinhEditField.Value = 'x^3 + x -1';
            app.taEditFieldLabel = uilabel(app.UIFigure);
            app.taEditFieldLabel.Text = 'từ a';
            app.tuaEditField = uieditfield(app.UIFigure, 'numeric');
            app.nbEditFieldLabel = uilabel(app.UIFigure);
            app.nbEditFieldLabel.Text = 'đến b ';
            app.denbEditField = uieditfield(app.UIFigure, 'numeric');
            app.denbEditField.Value = 1;
            app.khongphnlynghimLabel = uilabel(app.UIFigure);
            app.khongphnlynghimLabel.Text = 'khoảng phân ly nghiêm ';
            app.nhpsaischophpEditFieldLabel = uilabel(app.UIFigure);
            app.nhpsaischophpEditFieldLabel.Text = 'nhập sai số cho phép';
            app.nhapsaisochophepEditField = uieditfield(app.UIFigure, 'numeric');
            app.nhapsaisochophepEditField.Value = 0.003;
            app.chnphngphptmDropDownLabel = uilabel(app.UIFigure);
            app.chnphngphptmDropDownLabel.Text = 'chọn phương pháp tìm';
            app.chonphuongphaptimDropDown = uidropdown(app.UIFigure);
            app.chonphuongphaptimDropDown.Items = {'chia đôi', 'lặp', 'newton'};
            app.chonphuongphaptimDropDown.ValueChangedFcn = createCallbackFcn(app, @chonphuongphaptimDropDownValueChanged2, true);
            app.chonphuongphaptimDropDown.Value = 'chia đôi';
            app.batdauButton = uibutton(app.UIFigure, 'push');
            app.batdauButton.ButtonPushedFcn = createCallbackFcn(app, @batdauButtonPushed, true);
            app.batdauButton.Text = 'bắt đầu ';
            app.ktqunghimEditFieldLabel = uilabel(app.UIFigure);
            app.ktqunghimEditFieldLabel.Text = 'kết quả nghiệm';
            app.ketquanghiemEditField = uieditfield(app.UIFigure, 'numeric');
            app.slnlpEditFieldLabel = uilabel(app.UIFigure);
            app.slnlpEditFieldLabel.Text = 'số lần lập';
            app.solanlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.BiTonTmNghimLabel = uilabel(app.UIFigure);
            app.BiTonTmNghimLabel.Text = 'Bài Toán Tìm Nghiệm ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timnghiem2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end